classdef SCUT_LAOS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        btnDoAll            matlab.ui.control.Button
        btnClearTable       matlab.ui.control.Button
        btnDeleteRow        matlab.ui.control.Button
        btnAppend           matlab.ui.control.Button
        btnExportLissajous  matlab.ui.control.Button
        btnAnal             matlab.ui.control.Button
        tblLAOSParameters   matlab.ui.control.Table
        btnFullRange        matlab.ui.control.Button
        btnLastPoint        matlab.ui.control.Button
        btn1stPoint         matlab.ui.control.Button
        lstResultsSteps     matlab.ui.control.ListBox
        bgpVarToPlot        matlab.ui.container.ButtonGroup
        optPlotStress       matlab.ui.control.RadioButton
        optPlotStrain       matlab.ui.control.RadioButton
        ResultStepsLabel    matlab.ui.control.Label
        lblFileName         matlab.ui.control.Label
        btnOpen             matlab.ui.control.Button
        axeLissajous        matlab.ui.control.UIAxes
        axeTimeSignal       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        CurrentPath=pwd
        FileName
        units
        jsonStruct % Description
        resultsSteps % Description
        rows % Description
        rowsResultsStepsUuid
        blnPlotReady % Description
        currentWave
        currentWaveTitle
        % currentRange
        columnHeaders
        % plotVariables
        currentColumnHeaders
        firstPointIndex
        endPointIndex
        currentSamplingFrequency
        currentFrequency
        currentStrainAmplitude
        currentStrainPhase
        currentReconstructedWave
        currentLAOSParameters
        unitForModuli
    end

    methods (Access = private)

        function results = preventClose(app,dialogFig)


        end

        function results = closeDialog(app,dialogFig)
            delete(dialogFig);

            app.UIFigure.WindowState='normal';
            figure(app.UIFigure);

        end

        function results = replotWave(app)
            cla(app.axeTimeSignal);
            axis(app.axeTimeSignal,'on');

            if app.blnPlotReady
                if app.optPlotStrain.Value
                    plot(app.axeTimeSignal,app.currentWave(:,1),app.currentWave(:,2),...
                        'DisplayName',join([app.currentColumnHeaders{2,1},' (',app.currentColumnHeaders{2,3},')']),...
                        'LineWidth',1);
                    app.axeTimeSignal.YLabel.String=join([app.currentColumnHeaders{2,1},' (',app.currentColumnHeaders{2,3},')']);

                end
                if app.optPlotStress.Value
                    plot(app.axeTimeSignal,app.currentWave(:,1),app.currentWave(:,3),...
                        'DisplayName',join([app.currentColumnHeaders{3,1},' (',app.currentColumnHeaders{3,3},')']),...
                        'LineWidth',1);
                    app.axeTimeSignal.YLabel.String=join([app.currentColumnHeaders{3,1},' (',app.currentColumnHeaders{3,3},')']);
                end

                app.axeTimeSignal.YLimMode='auto';
                hold(app.axeTimeSignal,'on');
                YLimit=app.axeTimeSignal.YLim;
                plot(app.axeTimeSignal,app.currentWave(app.firstPointIndex,1).*ones(2,1),YLimit,'k--','LineWidth',1);
                plot(app.axeTimeSignal,app.currentWave(app.endPointIndex,1).*ones(2,1),YLimit,'k--','LineWidth',1);
                app.axeTimeSignal.YLim=YLimit;
                hold(app.axeTimeSignal,'off');


                % configure axes parameters
                app.axeTimeSignal.Title.String=app.currentWaveTitle;
                app.axeTimeSignal.XLabel.String=join([app.currentColumnHeaders{1,1},' (',app.currentColumnHeaders{1,3},')']);
            else
                cla(app.axeTimeSignal);
                axis(app.axeTimeSignal,'off');
                hold(app.axeTimeSignal,'on');

                text(app.axeTimeSignal,0.5,0.7,'No suitable data for analysis.',...
                    'Units','normalized',...
                    'HorizontalAlignment','center',...
                    'VerticalAlignment','middle');


            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btnOpen
        function btnOpenButtonPushed(app, event)
            % import matlab.io.xml.dom.*
            [file,path]=uigetfile('*.json','Select .json file',app.CurrentPath);
            figure(app.UIFigure);
            app.CurrentPath=path;
            app.FileName=file;
            blnSuccess=true;

            % handles cancel operation
            if file==0
                uialert(app.UIFigure, 'No file selected.', 'Notice', 'Icon', 'warning');
                app.CurrentPath=pwd;
                blnSuccess=false;
                app.lblFileName.Text="No file loaded.";
            end

            if blnSuccess

                % create a dialog to show the loading process
                dialogWidth=260;
                dialogHeight=75;

                mainFigPosition=app.UIFigure.Position;

                dialogX = mainFigPosition(1) + (mainFigPosition(3) - dialogWidth) / 2; % Center horizontally
                dialogY = mainFigPosition(2) + (mainFigPosition(4) - dialogHeight) / 2; % Center vertically

                dialogFig=uifigure('Name','Processing',...
                    'Position',[dialogX,dialogY,dialogWidth,dialogHeight],...
                    'WindowStyle','modal',...
                    'Resize','off',...
                    'CloseRequestFcn',@(src,event) preventClose(app,src));
                appPath=fileparts(mfilename('fullpath'));
                imagePath=fullfile(appPath,'icons8-clock.gif');

                gifImage=uiimage(dialogFig,...
                    'Position',[10,12.5,50,50],...
                    'ImageSource',imagePath);

                messageLabel=uilabel(dialogFig,...
                    'Text','Reading JSON file, please wait...',...
                    'Position',[70,43,180,22],...
                    'HorizontalAlignment','center');

                closeButton=uibutton(dialogFig,...
                    'Text','Close',...
                    'Position',[137.5,10,45,23],...
                    'Enable','off',...
                    'ButtonPushedFcn',@(btn,even)closeDialog(app,dialogFig));
                drawnow;

                % load the JSON as struct
                blnJSONSuccess=true;
                try
                    jsonText=fileread(fullfile(path,file));
                    app.jsonStruct=jsondecode(jsonText);
                catch ME
                    blnJSONSuccess=false;
                end
                if blnJSONSuccess
                    figure(dialogFig);
                    dialogFig.WindowStyle='modal';
                    messageLabel.Text='JSON file loaded successfully!';
                    closeButton.Enable='on';
                    gifImage.ImageSource=fullfile(appPath,'icons8-done.gif');
                    dialogFig.Name='Complete';
                    dialogFig.CloseRequestFcn=@(src,event) closeDialog(app,dialogFig);
                    % drawnow;
                else
                    figure(dialogFig);
                    dialogFig.WindowStyle='modal';
                    messageLabel.Text='Failed to load JSON file!';
                    closeButton.Enable='on';
                    gifImage.ImageSource=fullfile(appPath,'icons8-info.gif');
                    dialogFig.Name='Fail';
                    dialogFig.CloseRequestFcn=@(src,event) closeDialog(app,dialogFig);
                end
            end

            % sort out the result steps and column headers
            if blnSuccess & blnJSONSuccess
                app.lblFileName.Text=file;
                resultsStruct=app.jsonStruct.Results.Processed;

                app.resultsSteps=cell(length(resultsStruct.ResultsSteps),3);

                for i=1:length(resultsStruct.ResultsSteps)
                    app.resultsSteps{i,1}=resultsStruct.ResultsSteps(i).Id;
                    app.resultsSteps{i,2}=resultsStruct.ResultsSteps(i).Name;
                end

                app.lstResultsSteps.Items=app.resultsSteps(:,2);

                app.rows=resultsStruct.Rows;

                app.rowsResultsStepsUuid=cell(length(app.rows),1);
                for i=1:length(app.rows)
                    app.rowsResultsStepsUuid{i}=app.rows(i).ResultsStepId;
                end

                % Get all subnode names under ColumnHeaders
                columnHeaders=resultsStruct.ColumnHeaders;

                subNodes = fieldnames(columnHeaders);

                % Preload colume headers data
                numSubNodes = numel(subNodes);
                preloadedData=cell(numSubNodes, 3);

                for i=1:numSubNodes
                    currentSubNode=columnHeaders.(subNodes{i});
                    preloadedData{i,1}=currentSubNode.DisplayName;
                    preloadedData{i,2}=subNodes{i};
                    if isfield(currentSubNode,'Unit')
                        preloadedData{i,3}=currentSubNode.Unit.Name;
                    else
                        preloadedData{i,3}='';
                    end
                end
                app.columnHeaders=preloadedData;


                % clear previous data
                app.currentWaveTitle='';
                app.currentWave=[];
                app.currentColumnHeaders=[];
                % app.tblLAOSParameters.Data=[];
                cla(app.axeTimeSignal);
                cla(app.axeLissajous);

                % trigger the plotting of first step
                app.lstResultsSteps.Value=app.lstResultsSteps.Items{1};
                app.lstResultsStepsValueChanged;
            end
            figure(app.UIFigure)
        end

        % Value changed function: lstResultsSteps
        function lstResultsStepsValueChanged(app, event)
            value = app.lstResultsSteps.Value;
            app.blnPlotReady=true;

            % always use the first selected item for the current plot
            current_item=value;
            app.currentWaveTitle=current_item;

            % check for required columns
            % targetDisplayNames={'Step time', 'Strain', 'Stress', 'Normal stress'};
            targetDisplayNames={'Step time', 'Strain', 'Stress'};

            [found_column,idx_column]=ismember(targetDisplayNames,app.columnHeaders(:,1));
            [found_step,idx_step]=ismember(value,app.resultsSteps(:,2));


            if all([found_column(:);found_step(:)])

                % prepare for variable names and units
                matchingSubnodes=cell(numel(targetDisplayNames),3);
                for i=1:numel(targetDisplayNames)
                    matchingSubnodes{i,1}=app.columnHeaders{idx_column(i),1};
                    matchingSubnodes{i,2}=app.columnHeaders{idx_column(i),2};
                    matchingSubnodes{i,3}=app.columnHeaders{idx_column(i),3};
                end
                app.currentColumnHeaders=matchingSubnodes;

                % update headers of LAOS parameter

                app.tblLAOSParameters.ColumnName=[{join(['Strain amplitude (',matchingSubnodes{2,3},')'])},...
                    {join(['Strain frequency (',matchingSubnodes{1,3},'⁻¹)'])},...
                    {join(['Sampling frequency (',matchingSubnodes{1,3},'⁻¹)'])},...
                    {join(['G₁′ (',matchingSubnodes{3,3},')'])},...
                    {join(['G₁″ (',matchingSubnodes{3,3},')'])},...
                    {'I³⁄₁'},{'I⁵⁄₁'},{'I⁷⁄₁'},{'I⁹⁄₁'},...
                    {join(['Gᴍ (',matchingSubnodes{3,3},')'])},...
                    {join(['Gʟ (',matchingSubnodes{3,3},')'])},...
                    {join(['Gₖ (',matchingSubnodes{3,3},')'])},...
                    {'Nᴇ'},{'SNR₃ (dB)'},{'SNR₅ (dB)'},{'SNR₇ (dB)'},{'SNR₉ (dB)'}];


                % prepare for wave data
                currentRows=struct2table(app.rows(ismember(app.rowsResultsStepsUuid,app.resultsSteps{idx_step,1})));
                currentRange=app.resultsSteps{idx_step,3};

                [numCurrentRows,~]=size(currentRows);
                app.currentWave=zeros(numCurrentRows,numel(targetDisplayNames));
                for i=1:numel(targetDisplayNames)
                    app.currentWave(:,i)=currentRows{:,matchingSubnodes{i,2}};
                end
                app.currentWave=sortrows(app.currentWave,1);


                % read out the set range of this wave data
                if isempty(currentRange)
                    app.firstPointIndex=1;
                    app.endPointIndex=length(app.currentWave(:,1));
                else
                    app.firstPointIndex=currentRange(1);
                    app.endPointIndex=currentRange(2);
                end

                % estimate sampling frequency
                app.currentSamplingFrequency=1/mean(diff(app.currentWave(:,1)));


                % enable buttons
                app.btn1stPoint.Enable='on';
                app.btnLastPoint.Enable='on';
                app.btnFullRange.Enable='on';
                app.optPlotStrain.Enable='on';
                app.optPlotStress.Enable='on';
                app.btnDeleteRow.Enable='on';
                app.btnClearTable.Enable='on';
                app.btnExportLissajous.Enable='on';

                app.btnAnal.Enable='on';
                app.btnAppend.Enable='on';
                app.btnDoAll.Enable='on';

            else
                app.blnPlotReady=false;
                % disable buttons
                app.btn1stPoint.Enable='off';
                app.btnLastPoint.Enable='off';
                app.btnFullRange.Enable='off';
                app.optPlotStrain.Enable='off';
                app.optPlotStress.Enable='off';
                app.btnDeleteRow.Enable='off';
                app.btnClearTable.Enable='off';
                app.btnExportLissajous.Enable='off';


                app.btnAnal.Enable='off';
                app.btnAppend.Enable='off';
                app.btnDoAll.Enable='off';

            end
            % clear last parameters
            app.currentLAOSParameters=[];
            app.currentReconstructedWave=[];
            cla(app.axeLissajous);
            legend(app.axeLissajous,'off');

            replotWave(app);


        end

        % Selection changed function: bgpVarToPlot
        function bgpVarToPlotSelectionChanged(app, event)
            % selectedButton = app.bgpVarToPlot.SelectedObject;
            replotWave(app)

        end

        % Button pushed function: btn1stPoint
        function btn1stPointButtonPushed(app, event)
            % if isempty(app.currentWave)
            %     uialert(app.UIFigure,'No data')
            %     msgbox('No data.','Error','error','modal');
            %     return;
            % end
            fhv=app.UIFigure.HandleVisibility;
            app.UIFigure.HandleVisibility='callback';
            set(0,'CurrentFigure',app.UIFigure)
            axes(app.axeTimeSignal);
            [x,~,~]=ginput(1);
            app.UIFigure.HandleVisibility=fhv;

            % current_data=str2double(app.drpDataID.Value);
            if x<app.currentWave(1,1)
                x=app.currentWave(1,1);
            end
            if x>=app.currentWave(app.endPointIndex,1)
                x=app.currentWave(app.endPointIndex,1);
            end


            [~,I]=min(abs(app.currentWave(:,1)-x));
            app.firstPointIndex=I;

            % store the changed range
            [~,idx_step]=ismember(app.lstResultsSteps.Value,app.resultsSteps(:,2));

            if isempty(app.resultsSteps{idx_step,3})
                app.resultsSteps{idx_step,3}=[I,length(app.currentWave(:,1))];
            else
                app.resultsSteps{idx_step,3}(1)=I;
            end


            replotWave(app);

            % reset(app.axePlot)
            % app.axePlot.Box='on';
            % plot(app.axePlot,app.dat{current_data}(:,1),app.dat{current_data}(:,3),'LineWidth',1);
            % hold(app.axePlot,'on');
            % plot(app.axePlot,[app.dat{current_data}(app.fstpoints(current_data),1);...
            %     app.dat{current_data}(app.fstpoints(current_data),1)],...
            %     app.axePlot.YLim,'--');
            % plot(app.axePlot,[app.dat{current_data}(app.endpoints(current_data),1);...
            %     app.dat{current_data}(app.endpoints(current_data),1)],...
            %     app.axePlot.YLim,'--');
            % hold(app.axePlot,'off');
            %
            % app.axePlot.XLabel.String='time (s)';
            % app.axePlot.YLabel.String='load (N)';
        end

        % Button pushed function: btnLastPoint
        function btnLastPointButtonPushed(app, event)
            fhv=app.UIFigure.HandleVisibility;
            app.UIFigure.HandleVisibility='callback';
            set(0,'CurrentFigure',app.UIFigure)
            axes(app.axeTimeSignal);
            [x,~,~]=ginput(1);
            app.UIFigure.HandleVisibility=fhv;

            % current_data=str2double(app.drpDataID.Value);
            if x<app.currentWave(app.firstPointIndex,1)
                x=app.currentWave(app.firstPointIndex,1);
            end
            if x>=app.currentWave(end,1)
                x=app.currentWave(end,1);
            end


            [~,I]=min(abs(app.currentWave(:,1)-x));
            app.endPointIndex=I;

            % store the changed range
            [~,idx_step]=ismember(app.lstResultsSteps.Value,app.resultsSteps(:,2));

            if isempty(app.resultsSteps{idx_step,3})
                app.resultsSteps{idx_step,3}=[1,I];
            else
                app.resultsSteps{idx_step,3}(2)=I;
            end


            replotWave(app);

        end

        % Button pushed function: btnFullRange
        function btnFullRangeButtonPushed(app, event)
            app.firstPointIndex=1;
            app.endPointIndex=length(app.currentWave(:,1));

            % clear the stored range
            [~,idx_step]=ismember(app.lstResultsSteps.Value,app.resultsSteps(:,2));

            app.resultsSteps{idx_step,3}=[];
            replotWave(app);
        end

        % Button pushed function: btnAnal
        function btnAnalButtonPushed(app, event)
            if isempty(app.currentWave)

                dlg=uialert(app.UIFigure,'No data.','Info','Icon','info','Modal',true);
                waitfor(dlg);
            else
                blnSineFitSuccess=true;
                try

                    %% sine fitting
                    % Estimate the input frequency by FFT
                    N=app.endPointIndex-app.firstPointIndex+1; % number of points
                    Xraw=fft(app.currentWave(app.firstPointIndex:app.endPointIndex,2)); % do the FFT

                    cutoff=ceil(N/2); % use only one half of the FFT result
                    Xraw=2*Xraw(1:cutoff)/N; % normalize the amplitude
                    [I,J]=max(abs(Xraw(2:end))); % omit the DC and get the dominating harmonic
                    freq_res=app.currentSamplingFrequency/N; % recover freuqnecy axes
                    freq_raw=J*freq_res; % get the raw frequency of strain
                    m=mean(abs(Xraw)); % as an estimate of the noise floor level
                    snr=10*log10(I^2/m^2);

                    % reduce the noise before sine wave fitting by omitting some
                    % data
                    pts_per_cycle=app.currentSamplingFrequency/freq_raw;
                    if pts_per_cycle>snr
                        n_r=floor(app.currentSamplingFrequency/(abs(snr)*freq_raw));
                    else
                        n_r=1;
                    end

                    % the sine fitting
                    [params,~]=sinefit(app.currentWave(app.firstPointIndex:n_r:app.endPointIndex,2),...
                        app.currentWave(app.firstPointIndex:n_r:app.endPointIndex,1),freq_raw,0,0);
                    app.currentFrequency=params(3);
                    % rawStrainAmplitude=params(2);
                    % rawStrainPhase=wrapTo2Pi(pi/2+params(4));

                    % do a coherent sampling and FFT to get better estimate for
                    % strain amplitude and phase
                    N=app.endPointIndex-app.firstPointIndex+1;
                    pts_per_cycle=app.currentSamplingFrequency/app.currentFrequency;
                    Nr_cycles=floor(N/pts_per_cycle);
                    N_cut=round(Nr_cycles*pts_per_cycle);
                    x=app.currentWave(app.firstPointIndex:app.firstPointIndex+N_cut-1,2);
                    Xraw=2*fft(x)/N_cut;
                    Xraw=Xraw(1:ceil(N_cut/2));
                    % freq=0:app.currentSamplingFrequency/N_cut:app.currentSamplingFrequency/2-app.currentSamplingFrequency/N_cut;
                    A=abs(Xraw(round(N_cut*app.currentFrequency/app.currentSamplingFrequency)+1));
                    delta0=wrapTo2Pi(angle(Xraw(round(N_cut*app.currentFrequency/app.currentSamplingFrequency)+1))+0.5*pi);
                    app.currentStrainAmplitude=A;
                    app.currentStrainPhase=delta0;
                    % set(handles.txtFreq,'String',num2str(round(app.currentFrequency*100)/100));
                    % Update Lissajous
                    plot(app.axeLissajous,...
                        app.currentWave(app.firstPointIndex:app.endPointIndex,2),...
                        app.currentWave(app.firstPointIndex:app.endPointIndex,3),...
                        'DisplayName','Raw',...
                        'LineWidth',2);
                    app.axeLissajous.Title.String='Lissajous curve';
                    app.axeLissajous.XLabel.String=join(['Strain (',app.currentColumnHeaders{2,3},')']);
                    app.axeLissajous.YLabel.String=join(['Stress (',app.currentColumnHeaders{3,3},')']);


                catch ME
                    blnSineFitSuccess=false;
                    % Capture error message
                    errorMessage = ME.message;

                    % Create a more detailed error log
                    errorLog = {'Bad strain data.',...
                        ['Message: ',errorMessage]};

                    uialert(app.UIFigure,errorLog,'Error','Icon','error','Modal',true);
                end

                if blnSineFitSuccess
                    %% Perform FFT for stress
                    fi=app.currentFrequency; % input frequency
                    Nraw=app.endPointIndex-app.firstPointIndex+1;
                    Nr_cycles=floor(fi*Nraw/app.currentSamplingFrequency);
                    N=round(Nr_cycles*app.currentSamplingFrequency/fi); % cutoff for coherent sampling
                    freq_bin=0:N-1; % vector of the frequency bins
                    freq_res = app.currentSamplingFrequency/N; % frequency resolution
                    freq_i= freq_bin*freq_res; %frequency axis
                    Xraw=fft(app.currentWave(app.firstPointIndex:app.firstPointIndex+N-1,3)); % FFT of the stress
                    phase=angle(Xraw);
                    cutoff=ceil(N/2); % only use the first half of the FFT
                    freq_i=freq_i(1:cutoff);
                    Xraw=2*Xraw(1:cutoff)/N;
                    phase=phase(1:cutoff);
                    % plot(app.axeLissajous,abs(Xraw));

                    % determine the maximum number of harmonics by the criteria of
                    % spectral aliasing
                    max_nr_harm = floor(floor(app.currentSamplingFrequency/fi)/2);
                    if max_nr_harm/2 == floor(max_nr_harm/2)
                        max_nr_harm=max_nr_harm-1; % make it odd
                    end
                    % max_nr_harm=max_nr_harm-2;
                    % if max_nr_harm<9
                    %     max_nr_harm=9
                    % end

                    % there is no need to have too many harmonics even if no
                    % aliasing
                    if max_nr_harm>25
                        max_nr_harm=25;
                    end
                    % app.currentSamplingFrequency
                    %
                    % max_nr_harm


                    I_n=zeros((max_nr_harm+1)/2-1,1); % vector of the intensity
                    delta_n=zeros((max_nr_harm+1)/2-1,1); % vector of the phase
                    harmonicBins=zeros((max_nr_harm+1)/2-1,1); % bin indices of the odd harmonics

                    % note that max_nr_harm is always odd by design
                    for i=1:(max_nr_harm+1)/2-1
                        I_n(i)=abs(Xraw(round((2*i-1)*fi/freq_res)+1));
                        delta_n(i)=phase(round((2*i-1)*fi/freq_res)+1)+0.5*pi;
                        harmonicBins(i)=round((2*i-1)*fi/freq_res)+1;
                    end
                    DC=abs(Xraw(1))*cos(angle(Xraw(1)))/2;
                    % Calculate the nonlinear parameters
                    In1=I_n(2:(max_nr_harm+1)/2-1)/I_n(1);

                    %% Signal-to-noise ratio calculation
                    noiseIndices=setdiff(2:length(Xraw),harmonicBins);
                    noisePower=mean(abs(Xraw(noiseIndices)).^2);
                    % totalHarmonicPower = sum(abs(I_n(2:end)).^2);

                    % the signal-to-noise ratio of the 3rd, 5th, 7th, and
                    % 9th harmonics
                    SNR_dB = 10. * log10(I_n(2:5).^2./noisePower);
                    SNR_dB=SNR_dB(:)';


                    % for i = 1:length(oddHarmonics)
                    %     harmonic = oddHarmonics(i);
                    %     freq = harmonic * fi;
                    %     harmonicBins(i) = round(freq / (fs/N)) + 1;
                    % end
                    %
                    % totalPower=sum(abs(Xraw(2:end)).^2);
                    % allHarmonicPower=sum(I_n.^2);
                    % signalPower=sum(I_n(2:(max_nr_harm+1)/2-1).^2);
                    % noisePower=totalPower-allHarmonicPower;
                    %
                    % SNR_dB=10*log10(signalPower/noisePower);


                    %% Reconstruct stress wave
                    fs2=500; % the sampling frequency of the reconstructed signal is internally fixed to be 500 Hz
                    t2=app.currentWave(app.firstPointIndex,1):1/fs2:app.currentWave(app.endPointIndex,1);
                    t2=t2';


                    app.currentReconstructedWave=zeros(length(t2),4);

                    % the time column
                    app.currentReconstructedWave(:,1)=t2;

                    % reconstruct the strain wave
                    app.currentReconstructedWave(:,2)=app.currentStrainAmplitude*sin(2*pi*fi*t2+app.currentStrainPhase);

                    % reconstruct the strain-rate wave
                    app.currentReconstructedWave(:,3)=app.currentStrainAmplitude.*(2*pi*fi).*cos(2.*pi.*fi.*t2+app.currentStrainPhase);

                    % reconstruct the stress wave
                    for i=1:2:max_nr_harm-2
                        app.currentReconstructedWave(:,4)=app.currentReconstructedWave(:,4)+I_n((i+1)/2)*sin(i*2*pi*fi*t2+delta_n((i+1)/2));
                    end
                    % app.currentReconstructedWave(:,4)=app.currentReconstructedWave(:,4);%+DC;

                    hold(app.axeLissajous,'on');
                    plot(app.axeLissajous,app.currentReconstructedWave(:,2),app.currentReconstructedWave(:,4)+DC,...
                        'DisplayName','Reconstructed',...
                        'LineWidth',1)
                    lg=legend(app.axeLissajous);
                    lg.Location='best';

                    hold(app.axeLissajous,'off')
                    % title(app.axeLissajous,'Lissajous figure')
                    % xlabel(app.axeLissajous,'Strain (-)');ylabel(app.axeLissajous,'Stress (Pa)');
                    % legend(app.axeLissajous,'Original','Reconstructed','Location','NorthWest');


                    %% Calculate LAOS parameters using the reconstructed wave
                    A=app.currentReconstructedWave(:,4);
                    A=A-min(A)-(max(A)-min(A))/2;
                    A(A>0)=1;
                    A(A<0)=0;
                    zero_ind = find(diff(A)>0);
                    % plot(app.axeLissajous,app.currentReconstructedWave(zero_ind,1),app.currentReconstructedWave(zero_ind,2),'o',app.currentReconstructedWave(:,1),app.currentReconstructedWave(:,2),'-r')
                    A=app.currentReconstructedWave(:,[2,4]);
                    BB=A(:,2)-min(A(:,2))-(max(A(:,2))-min(A(:,2)))/2;
                    A(:,2)=BB;
                    G_k_raw=(A(zero_ind+1,2)-A(zero_ind,2))./(A(zero_ind+1,1)-A(zero_ind,1));
                    G_k=mean(G_k_raw);
                    A=app.currentReconstructedWave(:,2);
                    A(A>0)=1;
                    A(A<0)=0;
                    zero_ind=find(diff(A)>0);
                    A=app.currentReconstructedWave(:,[2,4]);
                    G_M=mean((A(zero_ind+1,2)-A(zero_ind,2))./(A(zero_ind+1,1)-A(zero_ind,1)));
                    % plot(reconst(zero_ind,1),reconst(zero_ind,2),'bo',...
                    %     reconst(:,1),reconst(:,2),'-r',...
                    %     reconst(zero_ind+1,1),reconst(zero_ind+1,2),'ko')
                    A=diff(app.currentReconstructedWave(:,2));
                    A(A>0)=1;
                    A(A<0)=0;
                    zero_ind=find(diff(A)<0);
                    A=app.currentReconstructedWave(:,[2,4]);
                    G_L=mean(A(zero_ind,2)./A(zero_ind,1));
                    % A=diff(app.currentReconstructedWave(:,2));
                    % A(A>0)=1;
                    % A(A<0)=0;
                    % zero_ind=find(diff(A)<0);
                    %
                    % A=app.currentReconstructedWave;
                    % G_p=mean(A(zero_ind,2)./A(zero_ind,1))
                    G_p=I_n(1)*cos(delta_n(1)-app.currentStrainPhase)/app.currentStrainAmplitude;
                    G_pp=I_n(1)*sin(delta_n(1)-app.currentStrainPhase)/app.currentStrainAmplitude;
                    N_E=(G_L-G_M)/G_p;
                    In1=In1';

                    % handle the issue of strain unit
                    if app.currentColumnHeaders{2,3}=='%'
                        G_k=G_k*100;
                        G_M=G_M*100;
                        G_L=G_L*100;
                        G_p=G_p*100;
                        G_pp=G_pp*100;
                    end


                    app.currentLAOSParameters=[abs(app.currentStrainAmplitude),...
                        app.currentFrequency,...
                        app.currentSamplingFrequency,...
                        G_p, G_pp, In1(1:4), G_M, G_L, G_k, N_E,SNR_dB];
                else
                    app.currentReconstructedWave=[];
                    cla(app.axeLissajous);
                    legend(app.axeLissajous,'off');
                    app.currentLAOSParameters=[];
                end
            end

            % set(handles.btnAddData, 'Enable','on');
            % set(handles.btnClearTable,'Enable','on');
            % set(handles.btnExportLissajous,'Enable','on');
            % guidata(hObject, handles);


        end

        % Button pushed function: btnAppend
        function btnAppendButtonPushed(app, event)
            if isempty(app.currentLAOSParameters)
                uialert(app.UIFigure,'No data to append.','Info','Icon','info','Modal',true);
            else
                if isempty(app.tblLAOSParameters.Data)
                    app.tblLAOSParameters.Data=app.currentLAOSParameters;
                else
                    data=app.tblLAOSParameters.Data;
                    data=[data;app.currentLAOSParameters];
                    app.tblLAOSParameters.Data=data;
                end
            end
        end

        % Button pushed function: btnDeleteRow
        function btnDeleteRowButtonPushed(app, event)
            % Get the indices of selected rows
            selectedRows = app.tblLAOSParameters.Selection;

            % Check if any rows are selected
            if ~isempty(selectedRows)
                % Get the current data
                currentData = app.tblLAOSParameters.Data;

                % Remove the selected rows
                % Use logical indexing to keep rows not in the selection
                currentData(selectedRows, :) = [];

                % Update the table data
                app.tblLAOSParameters.Data = currentData;

                % Optional: Clear the selection after deletion
                app.tblLAOSParameters.Selection = [];
            else
                % Optional: Show an alert if no rows are selected
                uialert(app.UIFigure, 'No rows selected', 'Info','Icon','info','Modal',true);
            end
        end

        % Button pushed function: btnClearTable
        function btnClearTableButtonPushed(app, event)
            app.tblLAOSParameters.Data=[];
        end

        % Button pushed function: btnExportLissajous
        function btnExportLissajousButtonPushed(app, event)
            if ~isempty(app.currentReconstructedWave)
                strFileName=join([app.FileName,'_',app.lstResultsSteps.Value,'_Lissajous.txt']);
                datLissajous=app.currentReconstructedWave;
                save(fullfile(app.CurrentPath,strFileName),'datLissajous','-ascii')
                metaLines={join(['Strain amplitude (',app.currentColumnHeaders{2,3},'): ',num2str(app.currentStrainAmplitude)]),...
                    join(['Strain frequency (',app.currentColumnHeaders{1,3},'⁻¹): ',num2str(app.currentFrequency)]),...
                    join(['Column 1: time (',app.currentColumnHeaders{1,3},')']),...
                    join(['Column 2: Strain (',app.currentColumnHeaders{2,3},')']),...
                    join(['Column 3: Strain rate (',app.currentColumnHeaders{2,3},' ',app.currentColumnHeaders{1,3},'⁻¹)']),...
                    join(['Column 4: Stress (',app.currentColumnHeaders{3,3},')'])};

                existingContent=fileread(fullfile(app.CurrentPath,strFileName));
                fid=fopen(fullfile(app.CurrentPath,strFileName),'w');
                for i=1:length(metaLines)
                    fprintf(fid,'%s\n',metaLines{i});
                end
                fprintf(fid,'%s',existingContent);
                fclose(fid);


            else
                dlg=uialert(app.UIFigure,'No Lissajous data.','Info','Icon','info','Modal',true);
                waitfor(dlg);
            end

        end

        % Button pushed function: btnDoAll
        function btnDoAllButtonPushed(app, event)
            % create a dialog to show the analyzing process
            dialogWidth=260;
            dialogHeight=75;

            mainFigPosition=app.UIFigure.Position;

            dialogX = mainFigPosition(1) + (mainFigPosition(3) - dialogWidth) / 2; % Center horizontally
            dialogY = mainFigPosition(2) + (mainFigPosition(4) - dialogHeight) / 2; % Center vertically

            dialogFig=uifigure('Name','Processing',...
                'Position',[dialogX,dialogY,dialogWidth,dialogHeight],...
                'WindowStyle','modal',...
                'Resize','off',...
                'CloseRequestFcn',@(src,event) preventClose(app,src));
            appPath=fileparts(mfilename('fullpath'));
            imagePath=fullfile(appPath,'icons8-clock.gif');

            gifImage=uiimage(dialogFig,...
                'Position',[10,12.5,50,50],...
                'ImageSource',imagePath);

            messageLabel=uilabel(dialogFig,...
                'Text','Analysing. Please wait...',...
                'Position',[70,43,180,22],...
                'HorizontalAlignment','center');

            closeButton=uibutton(dialogFig,...
                'Text','Close',...
                'Position',[137.5,10,45,23],...
                'Enable','off',...
                'ButtonPushedFcn',@(btn,even)closeDialog(app,dialogFig));
            drawnow;

            % analysis loop
            for i=1:length(app.lstResultsSteps.Items)

                % trigger selection change
                app.lstResultsSteps.Value=app.lstResultsSteps.Items{i};
                app.lstResultsStepsValueChanged;

                % trigger analysis
                % app.btnAnalButtonPushed(app, [])
                app.btnAnalButtonPushed;

                % trigger append to table
                app.btnAppendButtonPushed;

                % trigger export Lissajous
                app.btnExportLissajousButtonPushed;


            end


            % show the done dialog

            figure(dialogFig);
            dialogFig.WindowStyle='modal';
            messageLabel.Text='Complete!';
            closeButton.Enable='on';
            gifImage.ImageSource=fullfile(appPath,'icons8-done.gif');
            dialogFig.Name='Complete';
            dialogFig.CloseRequestFcn=@(src,event) closeDialog(app,dialogFig);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'LAOS data analysis';
            app.axeTimeSignal = uiaxes(app.UIFigure);
            title(app.axeTimeSignal, 'Title')
            xlabel(app.axeTimeSignal, 'X')
            ylabel(app.axeTimeSignal, 'Y')
            zlabel(app.axeTimeSignal, 'Z')
            app.axeLissajous = uiaxes(app.UIFigure);
            title(app.axeLissajous, 'Lissajous curve')
            xlabel(app.axeLissajous, 'X')
            ylabel(app.axeLissajous, 'Y')
            zlabel(app.axeLissajous, 'Z')
            app.btnOpen = uibutton(app.UIFigure, 'push');
            app.btnOpen.ButtonPushedFcn = createCallbackFcn(app, @btnOpenButtonPushed, true);
            app.btnOpen.Text = 'Open...';
            app.lblFileName = uilabel(app.UIFigure);
            app.lblFileName.Text = 'No file loaded.';
            app.ResultStepsLabel = uilabel(app.UIFigure);
            app.ResultStepsLabel.Text = 'Result Steps:';
            app.bgpVarToPlot = uibuttongroup(app.UIFigure);
            app.bgpVarToPlot.SelectionChangedFcn = createCallbackFcn(app, @bgpVarToPlotSelectionChanged, true);
            app.bgpVarToPlot.Title = 'Var to plot';
            app.optPlotStrain = uiradiobutton(app.bgpVarToPlot);
            app.optPlotStrain.Enable = 'off';
            app.optPlotStrain.Text = 'Strain';
            app.optPlotStrain.Value = true;
            app.optPlotStress = uiradiobutton(app.bgpVarToPlot);
            app.optPlotStress.Enable = 'off';
            app.optPlotStress.Text = 'Stress';
            app.lstResultsSteps = uilistbox(app.UIFigure);
            app.lstResultsSteps.Items = {''};
            app.lstResultsSteps.ValueChangedFcn = createCallbackFcn(app, @lstResultsStepsValueChanged, true);
            app.lstResultsSteps.Value = {};
            app.btn1stPoint = uibutton(app.UIFigure, 'push');
            app.btn1stPoint.ButtonPushedFcn = createCallbackFcn(app, @btn1stPointButtonPushed, true);
            app.btn1stPoint.Enable = 'off';
            app.btn1stPoint.Text = 'Select 1st point';
            app.btnLastPoint = uibutton(app.UIFigure, 'push');
            app.btnLastPoint.ButtonPushedFcn = createCallbackFcn(app, @btnLastPointButtonPushed, true);
            app.btnLastPoint.Enable = 'off';
            app.btnLastPoint.Text = 'Select last point';
            app.btnFullRange = uibutton(app.UIFigure, 'push');
            app.btnFullRange.ButtonPushedFcn = createCallbackFcn(app, @btnFullRangeButtonPushed, true);
            app.btnFullRange.Enable = 'off';
            app.btnFullRange.Text = 'Full range';
            app.tblLAOSParameters = uitable(app.UIFigure);
            app.tblLAOSParameters.ColumnName = {'column 1'; 'column 2'};
            app.tblLAOSParameters.RowName = {};
            app.tblLAOSParameters.SelectionType = 'row';
            app.btnAnal = uibutton(app.UIFigure, 'push');
            app.btnAnal.ButtonPushedFcn = createCallbackFcn(app, @btnAnalButtonPushed, true);
            app.btnAnal.Enable = 'off';
            app.btnAnal.Text = 'Analaysis';
            app.btnExportLissajous = uibutton(app.UIFigure, 'push');
            app.btnExportLissajous.ButtonPushedFcn = createCallbackFcn(app, @btnExportLissajousButtonPushed, true);
            app.btnExportLissajous.Enable = 'off';
            app.btnExportLissajous.Text = 'Export Lissajous';
            app.btnAppend = uibutton(app.UIFigure, 'push');
            app.btnAppend.ButtonPushedFcn = createCallbackFcn(app, @btnAppendButtonPushed, true);
            app.btnAppend.Enable = 'off';
            app.btnAppend.Text = 'Append to table';
            app.btnDeleteRow = uibutton(app.UIFigure, 'push');
            app.btnDeleteRow.ButtonPushedFcn = createCallbackFcn(app, @btnDeleteRowButtonPushed, true);
            app.btnDeleteRow.Enable = 'off';
            app.btnDeleteRow.Text = 'Delete row(s)';
            app.btnClearTable = uibutton(app.UIFigure, 'push');
            app.btnClearTable.ButtonPushedFcn = createCallbackFcn(app, @btnClearTableButtonPushed, true);
            app.btnClearTable.Enable = 'off';
            app.btnClearTable.Text = 'Clear table';
            app.btnDoAll = uibutton(app.UIFigure, 'push');
            app.btnDoAll.ButtonPushedFcn = createCallbackFcn(app, @btnDoAllButtonPushed, true);
            app.btnDoAll.Enable = 'off';
            app.btnDoAll.Text = 'Do all';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SCUT_LAOS

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end